classdef RecipeAdjuster < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ChickenTikkaButton           matlab.ui.control.Button
        ChickenPastaButton           matlab.ui.control.Button
        BeefBurgerFriesButton        matlab.ui.control.Button
        FishChipsButton              matlab.ui.control.Button
        CerealJuiceButton            matlab.ui.control.Button
        TotalCaloriesEditFieldLabel  matlab.ui.control.Label
        TotalCaloriesEditField       matlab.ui.control.NumericEditField
        ProteinEditFieldLabel        matlab.ui.control.Label
        ProteinEditField             matlab.ui.control.NumericEditField
        CarbRangeButton              matlab.ui.control.Button
        CarbEditFieldLabel           matlab.ui.control.Label
        CarbEditField                matlab.ui.control.NumericEditField
        CalculateButton              matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        WarningfoodmightnottastegoodLabel  matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        a1;a2;a3;
        b1;b2;b3;
        c1;c2;c3;
        TC;d1;d2;
        UpperLimit;LowerLimit;
        X;Y;Z;
    
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.a1 = 0;app.a2 = 0;app.a3 = 0;
            app.b1 = 0;app.b2 = 0;app.b3 = 0;
            app.c1 = 0;app.c2 = 0;app.c3 = 0;
            
            app.X = 'X'; app.Y = 'Y'; app.Z = 'Z';
            
        end

        % Button pushed function: ChickenTikkaButton
        function ChickenTikkaButtonPushed(app, event)
            % Set Values For Three Ingredients
            app.a1 = 12.4;app.a2 = 3.5;app.a3 = 8.1;
            app.b1 = 0.3;app.b2 = 4.7;app.b3 = 77.1;
            app.c1 = 2.5;app.c2 = 3.3;app.c3 = 0.6;
            
            app.X = 'Chicken'; app.Y = 'Yogurt'; app.Z = 'Rice';
        end

        % Button pushed function: ChickenPastaButton
        function ChickenPastaButtonPushed(app, event)
            app.a1 = 12.4;app.a2 = 12.4;app.a3 = 0.3;
            app.b1 = 0.3;app.b2 = 71.3;app.b3 = 6.8;
            app.c1 = 2.5;app.c2 = 1.9;app.c3 = 0.8;
            
            app.X = 'Chicken'; app.Y = 'Pasta'; app.Z = 'Tomato Sauce';
        end

        % Button pushed function: BeefBurgerFriesButton
        function BeefBurgerFriesButtonPushed(app, event)
            app.a1 = 8.5;app.a2 = 32.4;app.a3 = 3;
            app.b1 = 44.3;app.b2 = 0.3;app.b3 = 37;
            app.c1 = 2;app.c2 = 6.5;app.c3 = 15;
            
            app.X = 'Bread'; app.Y = 'Beef Patty'; app.Z = 'Fries';
        end

        % Button pushed function: FishChipsButton
        function FishChipsButtonPushed(app, event)
            app.a1 = 25;app.a2 = 3;app.a3 = 1.1;
            app.b1 = 0.7;app.b2 = 37;app.b3 = 1.4;
            app.c1 = 2.5;app.c2 = 15;app.c3 = 79;
            
            app.X = 'Fish'; app.Y = 'Fries'; app.Z = 'Mayonnaise';
        end

        % Button pushed function: CerealJuiceButton
        function CerealJuiceButtonPushed(app, event)
            app.a1 = 7;app.a2 = 3.4;app.a3 = 0.7;
            app.b1 = 84;app.b2 = 5;app.b3 = 10;
            app.c1 = 0.9;app.c2 = 1;app.c3 = 0.2;
            
            app.X = 'Cornflakes'; app.Y = 'Milk'; app.Z = 'Orange Juice';
        end

        % Value changed function: TotalCaloriesEditField
        function TotalCaloriesEditFieldValueChanged(app, event)
            value = app.TotalCaloriesEditField.Value;
            app.TC = value;
        end

        % Value changed function: ProteinEditField
        function ProteinEditFieldValueChanged(app, event)
            value = app.ProteinEditField.Value;
            app.d1 = value;
        end

        % Button pushed function: CarbRangeButton
        function CarbRangeButtonPushed(app, event)

            Xgrad = (app.c1 - ((app.b1/app.b2)*app.c2))/(((app.b1/app.b2)*app.a2)-app.a1);

            Ygrad = (app.c1 - ((app.a1/app.a2)*app.c2))/(((app.a1/app.a2)*app.b2)-app.b1);
            
            FirstLimitTop = ((app.a1*app.b1)/app.a2) - ((app.d1*app.b1)/app.b2) + ((app.a1*app.a1)/app.a2) - ((app.b1*app.b1)/app.b2);
            FirstLimitBot = app.a1 - app.b1 + ((app.a1*app.b2)/app.a2) - ((app.a2*app.b1)/app.b2);

            % Set limits for carb range based on protein
            % most the time not needed but it is used to 
            % make sure there are no negative values for quantity
            % How it works: explained in the report

            if (Xgrad > 0 && Ygrad < 0)
            
                if (((app.a1/app.a2)*app.b2) > app.b1)
                
                    if (((app.b1/app.b2)*app.a2) > app.a1)
                    app.LowerLimit = max((app.d1*(app.b2/app.b1)), (app.d1*(app.a2/app.a1)), 0);
                
                    else
                    app.LowerLimit = max(0, (app.d1*(app.a2/app.a1)));
                    app.UpperLimit = min((98 - app.d1), (app.d1*(app.b2/app.b1)));
                
                    end
                    if (app.UpperLimit < app.LowerLimit)
                        app.UpperLimit = min((98 - app.d1), (app.d1*(app.a2/app.a1)));
                        app.LowerLimit = max(0, (app.d1*(app.b2/app.b1)));
                    end
            
                end
            else 
                if (((app.b1/app.b2)*app.a2) > app.a1)
                    app.LowerLimit = max(0, (app.d1*(app.b2/app.b1)));
                    app.UpperLimit = min((98 - app.d1), (app.d1*(app.a2/app.a1)));
                    if (app.UpperLimit < app.LowerLimit)
                        app.UpperLimit = min((98 - app.d1), (app.d1*(app.b2app.b1)));
                        app.LowerLimit = max(0, (app.d1*(app.a2/app.a1)));
                    end
                else
                    app.UpperLimit = min((app.d1*(app.b2/app.b1)), (app.d1*(app.a2/app.a1)), (98 - app.d1));
                end
            end
        
            if ((Xgrad > 0 && Ygrad < 0) || (Xgrad < 0 && Ygrad > 0))
            app.LowerLimit = max(0, app.d1/(FirstLimitTop/FirstLimitBot));
            app.UpperLimit = min((98 - app.d1), app.d1/(app.c1/app.c2));
            
            if (app.UpperLimit < app.LowerLimit)
                app.UpperLimit = min((98 - app.d1), app.d1/(FirstLimitTop/FirstLimitBot));
                app.LowerLimit = max(0, app.d1/(app.c1/app.c2));
            end
        
            else
            app.LowerLimit = 0;
            app.UpperLimit = 98 - app.d1;
            end
        formatSpec = 'Choose Carb percentage between: %d %s %d';
        A1 = app.LowerLimit;
        A2 = ' and ';
        A3 = app.UpperLimit;
        
        app.Label.Text = sprintf(formatSpec,A1,A2,A3);
        drawnow
        end

        % Value changed function: CarbEditField
        function CarbEditFieldValueChanged(app, event)
            value = app.CarbEditField.Value;
            app.d2 = value;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            d3 = 100 - (app.d2 + app.d1);
            
            % Solve the three by three simultaneous to give the quanity outputs
            % Display quantity outputs
            
            AnswerX = abs(((((((app.c1/app.c2)*app.b2) - app.b1) / (((app.c1/app.c3)*app.b3) - app.b1)) * (((app.c1/app.c3)*d3) - app.d1)) - ((app.c1/app.c2*app.d2) - app.d1)) / ((((((app.c1/app.c2)*app.b2) - app.b1) / ((app.c1/app.c3*app.b3) - app.b1)) * ((app.c1/app.c3*app.a3) - app.a1)) - ((app.c1/app.c2*app.a2) - app.a1)));
            AnswerY = abs(((((((app.a1/app.a2)*app.c2) - app.c1) / (((app.a1/app.a3)*app.c3) - app.c1)) * (((app.a1/app.a3)*d3) - app.d1)) - ((app.a1/app.a2*app.d2) - app.d1)) / ((((((app.a1/app.a2)*app.c2) - app.c1) / ((app.a1/app.a3*app.c3) - app.c1)) * ((app.a1/app.a3*app.b3) - app.b1)) - ((app.a1/app.a2*app.b2) - app.b1)));
            AnswerZ = abs(((((((app.a1/app.a2)*app.b2) - app.b1) / (((app.a1/app.a3)*app.b3) - app.b1)) * (((app.a1/app.a3)*d3) - app.d1)) - ((app.a1/app.a2*app.d2) - app.d1)) / ((((((app.a1/app.a2)*app.b2) - app.b1) / ((app.a1/app.a3*app.b3) - app.b1)) * ((app.a1/app.a3*app.c3) - app.c1)) - ((app.a1/app.a2*app.c2) - app.c1)));
            
            OutputX = round(app.TC*AnswerX);
            OutputY = round(app.TC*AnswerY);
            OutputZ = round(app.TC*AnswerZ);
            
            str = 'To get your desired calories and macros use: ';
            A1 = OutputX; A2 = 'g of '; A3 = app.X; A4 = OutputY;
            A5 = app.Y; A7 = OutputZ; A8 = app.Z;
            app.Label_2.Text = str;
            drawnow
            app.Label_3.Text = sprintf('%d_%s_%s',A1,A2,A3);
            drawnow
            app.Label_4.Text = sprintf('%d_%s_%s',A4,A2,A5);
            drawnow
            app.Label_5.Text = sprintf('%d_%s_%s',A7,A2,A8);
            drawnow
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ChickenTikkaButton = uibutton(app.UIFigure, 'push');
            app.ChickenTikkaButton.ButtonPushedFcn = createCallbackFcn(app, @ChickenTikkaButtonPushed, true);
            app.ChickenTikkaButton.Text = 'Chicken Tikka';
            app.ChickenPastaButton = uibutton(app.UIFigure, 'push');
            app.ChickenPastaButton.ButtonPushedFcn = createCallbackFcn(app, @ChickenPastaButtonPushed, true);
            app.ChickenPastaButton.Text = 'Chicken Pasta';
            app.BeefBurgerFriesButton = uibutton(app.UIFigure, 'push');
            app.BeefBurgerFriesButton.ButtonPushedFcn = createCallbackFcn(app, @BeefBurgerFriesButtonPushed, true);
            app.BeefBurgerFriesButton.Text = 'Beef Burger & Fries';
            app.FishChipsButton = uibutton(app.UIFigure, 'push');
            app.FishChipsButton.ButtonPushedFcn = createCallbackFcn(app, @FishChipsButtonPushed, true);
            app.FishChipsButton.Text = 'Fish & Chips';
            app.CerealJuiceButton = uibutton(app.UIFigure, 'push');
            app.CerealJuiceButton.ButtonPushedFcn = createCallbackFcn(app, @CerealJuiceButtonPushed, true);
            app.CerealJuiceButton.Text = 'Cereal & Juice';
            app.TotalCaloriesEditFieldLabel = uilabel(app.UIFigure);
            app.TotalCaloriesEditFieldLabel.Text = 'Total Calories';
            app.TotalCaloriesEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalCaloriesEditField.ValueChangedFcn = createCallbackFcn(app, @TotalCaloriesEditFieldValueChanged, true);
            app.ProteinEditFieldLabel = uilabel(app.UIFigure);
            app.ProteinEditFieldLabel.Text = 'Protein %';
            app.ProteinEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProteinEditField.ValueChangedFcn = createCallbackFcn(app, @ProteinEditFieldValueChanged, true);
            app.CarbRangeButton = uibutton(app.UIFigure, 'push');
            app.CarbRangeButton.ButtonPushedFcn = createCallbackFcn(app, @CarbRangeButtonPushed, true);
            app.CarbRangeButton.Text = 'Carb Range';
            app.CarbEditFieldLabel = uilabel(app.UIFigure);
            app.CarbEditFieldLabel.Text = 'Carb %';
            app.CarbEditField = uieditfield(app.UIFigure, 'numeric');
            app.CarbEditField.ValueChangedFcn = createCallbackFcn(app, @CarbEditFieldValueChanged, true);
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.WarningfoodmightnottastegoodLabel = uilabel(app.UIFigure);
            app.WarningfoodmightnottastegoodLabel.Text = 'Warning: food might not taste good';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RecipeAdjuster

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end